classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ExporterDropDown      matlab.ui.control.DropDown
        OuvrirButton          matlab.ui.control.Button
        DIC2GIT20222023Label  matlab.ui.control.Label
        StyleMELabel          matlab.ui.control.Label
        Image_2               matlab.ui.control.Image
        Panel                 matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        AppliquerButton       matlab.ui.control.Button
        ResolutionLabel       matlab.ui.control.Label
        ResolutionSwitch      matlab.ui.control.Switch
        ButtonRight           matlab.ui.control.Button
        ButtonLeft            matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        StyleDropDown         matlab.ui.control.DropDown
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        networks;
        loadedImage = "";
        currentImage = "";
        dropdownMap;
        currentDropDownIndex = 1;
        message;
        hcode;
        dict;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource = "";
            path = "NST";
            contents = what(path);
            filenames = contents.mat;

            app.StyleDropDown.Items = "Aucun";
            app.StyleDropDown.Value = "Aucun";

            % Chargement de tous les fichiers
            for i = 1:numel(filenames)
                % Chargement de dlnetwork
                pretrainedNetwork = load(fullfile(path,filenames{i}));
                % Retrait de l'extension .mat
                filename_no_extension = filenames{i}(1:end-4);
                % ajout de dlnetwork aux networks
                app.networks.(filename_no_extension) = pretrainedNetwork.dlnetTransform;
                % Test du network
                randImg = dlarray(rand([2 2 3]),'SSC');
                forward(app.networks.(filename_no_extension),randImg);
                % Ajout du network au dropdown
                app.StyleDropDown.Items{end+1} = filename_no_extension;
            end
            keySet = app.StyleDropDown.Items;
            valueSet = 1: length(app.StyleDropDown.Items);
            app.dropdownMap = containers.Map(keySet,valueSet);
        end

        % Button pushed function: OuvrirButton
        function OuvrirButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.png'}, "Ouvrir un fichier");
            fullpathname = strcat(pathname, filename);
            app.loadedImage = imread(fullpathname);
            app.currentImage = app.loadedImage;
            imshow(app.currentImage, 'parent', app.UIAxes);
        end

        % Value changed function: ResolutionSwitch
        function ResolutionSwitchValueChanged(app, event)
            cd './SR'
            value = app.ResolutionSwitch.Value;
            if ~isa(app.loadedImage,'string')
                if value == "Basse"
                    imshow(app.loadedImage, "parent", app.UIAxes);
                else
                    imgSR = ESRGAN_2xSuperResolution(app.currentImage);
                    disp("2x super resolution");
                    app.currentImage = im2uint8(ESRGAN_2xSuperResolution(imgSR));
                    imshow(app.currentImage, 'parent', app.UIAxes);
                    disp('Done');
                end
            end
            cd '../'
        end

        % Value changed function: StyleDropDown
        function StyleDropDownValueChanged(app, event)
            switch app.StyleDropDown.Value
                case 'Aucun' 
                    app.Image.ImageSource = "";
                otherwise
                    stylesDirectory = what('assets/styles');
                    stylesPath = stylesDirectory.path;
                    imageFileName = fullfile(stylesPath,[app.StyleDropDown.Value,'.jpg']);
                    if isfile(imageFileName)
                        app.Image.ImageSource = imageFileName;
                    else
                        app.Image.ImageSource = "";
                    end 
            end

            app.currentDropDownIndex = app.dropdownMap(app.StyleDropDown.Value);
        end

        % Button pushed function: ButtonLeft
        function ButtonLeftPushed(app, event)
            if app.currentDropDownIndex > 1
                app.currentDropDownIndex = app.currentDropDownIndex - 1;
            else
                app.currentDropDownIndex = length(app.StyleDropDown.Items);
            end
            app.StyleDropDown.Value = app.StyleDropDown.Items{app.currentDropDownIndex};
            app.StyleDropDownValueChanged()
        end

        % Button pushed function: ButtonRight
        function ButtonRightPushed(app, event)
            if app.currentDropDownIndex < length(app.StyleDropDown.Items)
                app.currentDropDownIndex = app.currentDropDownIndex + 1;
            else
                app.currentDropDownIndex = 1;
            end
            app.StyleDropDown.Value = app.StyleDropDown.Items{app.currentDropDownIndex};
            app.StyleDropDownValueChanged()
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
            if ~(app.StyleDropDown.Value == "Aucun")
                if ~isa(app.loadedImage,'string')
                    if ~isa(app.currentImage, 'string')
                        dlX = dlarray(single(app.currentImage),'SSCB');
                        dlY = predict(app.networks.(string(app.StyleDropDown.Value)),dlX);
                        Y = 255*(tanh(dlY)+1)/2;
                        app.currentImage= uint8(gather(extractdata(Y)));
                        imshow(app.currentImage,'parent',app.UIAxes, 'InitialMagnification','fit');
                    end
                end
            else
                app.currentImage = app.loadedImage;
                imshow(app.currentImage,'parent',app.UIAxes, 'InitialMagnification','fit');
            end
        end

        % Callback function
        function MessageEditFieldValueChanged(app, event)
            value = app.MessageEditField.Value;
            app.message =  value;
        end

        % Callback function
        function EncoderButtonPushed(app, event)

        end

        % Callback function
        function DcoderButtonPushed(app, event)

        end

        % Value changed function: ExporterDropDown
        function ExporterDropDownValueChanged(app, event)
            imgsave = app.currentImage;
            if (app.ExporterDropDown.Value == "JPG")
                prompt={'Entrer le nom du fichier'};
                dlgtitle="Exporter";
                definput={'img'};
                opts.Interpreter='tex';
                filename=inputdlg(prompt,dlgtitle,[1 40],definput,opts);
                imwrite(imgsave, strcat(filename,".jpg"), "jpg");
            elseif (app.ExporterDropDown.Value == "PNG")
                prompt={'Entrer le nom du fichier'};
                dlgtitle="Exporter";
                definput={'img'};
                opts.Interpreter='tex';
                filename=inputdlg(prompt,dlgtitle,[1 40],definput,opts);
                imwrite(imgsave, strcat(filename,".png"), "png");
            elseif (app.ExporterDropDown.Value == "TIF")
                prompt={'Entrer le nom du fichier'};
                dlgtitle="Exporter";
                definput={'img'};
                opts.Interpreter='tex';
                filename=inputdlg(prompt,dlgtitle,[1 40],definput,opts);
                imwrite(imgsave, strcat(filename,".gif"), "tif");
            elseif (app.ExporterDropDown.Value == "BMP")
                prompt={'Entrer le nom du fichier'};
                dlgtitle="Exporter";
                definput={'img'};
                opts.Interpreter='tex';
                filename=inputdlg(prompt,dlgtitle,[1 40],definput,opts);
                imwrite(imgsave, strcat(filename,".bmp"), "bmp");
            end
            app.ExporterDropDown.Value = "Exporter";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.StyleDropDown = uidropdown(app.GridLayout);
            app.StyleDropDown.Items = {'Aucun'};
            app.StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @StyleDropDownValueChanged, true);
            app.StyleDropDown.Tooltip = {''; ''};
            app.StyleDropDown.Value = 'Aucun';
            app.Image = uiimage(app.GridLayout);
            app.ButtonLeft = uibutton(app.GridLayout, 'push');
            app.ButtonLeft.ButtonPushedFcn = createCallbackFcn(app, @ButtonLeftPushed, true);
            app.ButtonLeft.Text = {'<<'; ''};
            app.ButtonRight = uibutton(app.GridLayout, 'push');
            app.ButtonRight.ButtonPushedFcn = createCallbackFcn(app, @ButtonRightPushed, true);
            app.ButtonRight.Text = {'>>'; ''};
            app.ResolutionSwitch = uiswitch(app.GridLayout, 'slider');
            app.ResolutionSwitch.Items = {'Basse', 'Haute'};
            app.ResolutionSwitch.ValueChangedFcn = createCallbackFcn(app, @ResolutionSwitchValueChanged, true);
            app.ResolutionSwitch.Value = 'Basse';
            app.ResolutionLabel = uilabel(app.GridLayout);
            app.ResolutionLabel.Text = 'Resolution';
            app.AppliquerButton = uibutton(app.GridLayout, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'assets', 'logo_ept.png');
            app.StyleMELabel = uilabel(app.UIFigure);
            app.StyleMELabel.Text = 'StyleME';
            app.DIC2GIT20222023Label = uilabel(app.UIFigure);
            app.DIC2GIT20222023Label.Text = 'DIC2 GIT 2022/2023';
            app.OuvrirButton = uibutton(app.UIFigure, 'push');
            app.OuvrirButton.ButtonPushedFcn = createCallbackFcn(app, @OuvrirButtonPushed, true);
            app.OuvrirButton.Text = 'Ouvrir';
            app.ExporterDropDown = uidropdown(app.UIFigure);
            app.ExporterDropDown.Items = {'Exporter', 'JPG', 'PNG', 'TIF', 'BMP'};
            app.ExporterDropDown.ValueChangedFcn = createCallbackFcn(app, @ExporterDropDownValueChanged, true);
            app.ExporterDropDown.Value = 'Exporter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end